classdef acf_interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CheckparametersforautocorrelationfunctionUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        maxlagtimeLabel             matlab.ui.control.Label
        normalizationsizeEditFieldLabel  matlab.ui.control.Label
        normalizationsizeEditField  matlab.ui.control.NumericEditField
        cleaningileEditFieldLabel   matlab.ui.control.Label
        percentileEditField         matlab.ui.control.NumericEditField
        Spinner_tdim                matlab.ui.control.Spinner
        RarelyusedparametersLabel   matlab.ui.control.Label
        RecalculateButton           matlab.ui.control.Button
        frLabel                     matlab.ui.control.Label
        spatialresolutionLabel      matlab.ui.control.Label
        Spinner_xres                matlab.ui.control.Spinner
        pxLabel_4                   matlab.ui.control.Label
        spatialextentLabel          matlab.ui.control.Label
        Spinner_xdim                matlab.ui.control.Spinner
        pxLabel_3                   matlab.ui.control.Label
        DoneButton                  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % an acfDataStorage object containing the data we need / want to return
        isPlotting = false; % switch to keep the figure from appearing too early
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.data = data;
            app.Spinner_xdim.Value = app.data.params.acfSpec.xdim;
            app.Spinner_tdim.Value = app.data.params.acfSpec.tdim;
            app.Spinner_xres.Value = app.data.params.acfSpec.xres;
            app.percentileEditField.Value = app.data.params.acfCleanBackgroundPercentile;
            app.normalizationsizeEditField.Value = app.data.params.acfCleanNormalizationSizePx;
            RecalculateButtonPushed(app, 0)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CheckparametersforautocorrelationfunctionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {444, 444};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)
            % Save parameters
            app.data.params.acfSpec.xdim = app.Spinner_xdim.Value;
            app.data.params.acfSpec.tdim = app.Spinner_tdim.Value;
            app.data.params.acfSpec.xres = app.Spinner_xres.Value;
            app.data.params.acfCleanBackgroundPercentile = app.percentileEditField.Value;
            app.data.params.acfCleanNormalizationSizePx = app.normalizationsizeEditField.Value;
            
            % Recalculate acf
            if app.isPlotting
                app.Image.Visible = 'on';
                drawnow;
            end
            app.data.acf_noclean = acf3d(app.data.detections, app.data.params.acfSpec, ...
                                 'normalization', 'count', ...
                                 'verbose', app.data.params.verbose);
            app.data.acf = clean_acf(app.data.acf_noclean, ...
                                     'percentile', app.data.params.acfCleanBackgroundPercentile, ...
                                     'normalizationSize', app.data.params.acfCleanNormalizationSizePx/app.data.params.acfSpec.xres);
                                 
            if ~app.data.params.checkACF
                app.delete;
            else
                % Plot
                cla(app.UIAxes);
                vol3d('CData', app.data.acf, 'XData', [-app.data.params.acfSpec.xdim, app.data.params.acfSpec.xdim], ...
                                             'YData', [-app.data.params.acfSpec.xdim, app.data.params.acfSpec.xdim], ...
                                             'ZData', [-app.data.params.acfSpec.tdim, app.data.params.acfSpec.tdim], ...
                                             'Parent', app.UIAxes);
                colorbar(app.UIAxes);
                view(app.UIAxes, 3);
                app.Image.Visible = 'off';
                drawnow;
                
                app.isPlotting = true;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CheckparametersforautocorrelationfunctionUIFigure = uifigure('Visible', 'off');
            app.CheckparametersforautocorrelationfunctionUIFigure.Name = 'Check parameters for autocorrelation function';
            app.CheckparametersforautocorrelationfunctionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CheckparametersforautocorrelationfunctionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.spatialextentLabel = uilabel(app.LeftPanel);
            app.spatialextentLabel.Text = 'spatial extent';
            app.Spinner_xdim = uispinner(app.LeftPanel);
            app.Spinner_xdim.Limits = [0 Inf];
            app.pxLabel_3 = uilabel(app.LeftPanel);
            app.pxLabel_3.Text = 'px';
            app.spatialresolutionLabel = uilabel(app.LeftPanel);
            app.spatialresolutionLabel.Text = 'spatial resolution';
            app.Spinner_xres = uispinner(app.LeftPanel);
            app.Spinner_xres.Step = 0.1;
            app.Spinner_xres.Limits = [0 Inf];
            app.pxLabel_4 = uilabel(app.LeftPanel);
            app.pxLabel_4.Text = 'px';
            app.maxlagtimeLabel = uilabel(app.LeftPanel);
            app.maxlagtimeLabel.Text = 'max lag time';
            app.Spinner_tdim = uispinner(app.LeftPanel);
            app.Spinner_tdim.Limits = [0 Inf];
            app.frLabel = uilabel(app.LeftPanel);
            app.frLabel.Text = 'fr';
            app.RecalculateButton = uibutton(app.LeftPanel, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Text = 'Recalculate';
            app.RarelyusedparametersLabel = uilabel(app.LeftPanel);
            app.RarelyusedparametersLabel.Text = 'Rarely used parameters';
            app.cleaningileEditFieldLabel = uilabel(app.LeftPanel);
            app.cleaningileEditFieldLabel.Text = 'cleaning %-ile';
            app.percentileEditField = uieditfield(app.LeftPanel, 'numeric');
            app.normalizationsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.normalizationsizeEditFieldLabel.Text = 'normalization size';
            app.normalizationsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DoneButton = uibutton(app.LeftPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Current acf')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Image = uiimage(app.RightPanel);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'acf_interactive_block.png';
            % Show the figure after all components are created
            app.CheckparametersforautocorrelationfunctionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acf_interactive(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CheckparametersforautocorrelationfunctionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CheckparametersforautocorrelationfunctionUIFigure)
        end
    end
end